classdef powerModelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayoutMain                  matlab.ui.container.GridLayout
        WarningTextArea                 matlab.ui.control.TextArea
        PowerModelGUILabel              matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
        SimulationSettingsPanel         matlab.ui.container.Panel
        ResourceDataTypeDropDown        matlab.ui.control.DropDown
        ResourceDataTypeDropDownLabel   matlab.ui.control.Label
        DependentVariableDropDown       matlab.ui.control.DropDown
        DependentVariableDropDownLabel  matlab.ui.control.Label
        SimulationTimestepsEditField    matlab.ui.control.NumericEditField
        SimulationTimestepsEditFieldLabel  matlab.ui.control.Label
        SimulationDurationhrEditField   matlab.ui.control.NumericEditField
        SimulationDurationhrLabel       matlab.ui.control.Label
        ResourceDataWaveTimeSeriesUploadPanel  matlab.ui.container.Panel
        DatafileWaveTextArea            matlab.ui.control.TextArea
        DatafileTextAreaLabel           matlab.ui.control.Label
        DataisacsvtablecontainingtherequiredheadersCheckBox  matlab.ui.control.CheckBox
        SelectresourcedatafileWaveLabel  matlab.ui.control.Label
        BrowseWaveButton                matlab.ui.control.Button
        VariableSelectionWaveData       matlab.ui.container.Panel
        SelecttimevariableDropDown_2    matlab.ui.control.DropDown
        SelecttimevariableDropDown_2Label  matlab.ui.control.Label
        SelectpeakperiodvariableDropDown  matlab.ui.control.DropDown
        SelectpeakperiodvariableDropDownLabel  matlab.ui.control.Label
        SelectwaveenergyperiodvariableDropDown  matlab.ui.control.DropDown
        SelectwaveenergyperiodvariableDropDownLabel  matlab.ui.control.Label
        SelectsignificantwaveheightvariableDropDown  matlab.ui.control.DropDown
        SelectsignificantwaveheightvariableDropDownLabel  matlab.ui.control.Label
        ResourceDataPwrMatTimeSeriesUploadPanel  matlab.ui.container.Panel
        DatafilePwrMatTextArea          matlab.ui.control.TextArea
        DatafileTextAreaLabel_2         matlab.ui.control.Label
        VariableSelectionPwrMatData     matlab.ui.container.Panel
        SelectwaveenergyperiodPwrMatDropDown  matlab.ui.control.DropDown
        SelecttimevariableDropDown_3Label_3  matlab.ui.control.Label
        SelectsignificantwaveheightPwrMatDropDown  matlab.ui.control.DropDown
        SelecttimevariableDropDown_3Label_2  matlab.ui.control.Label
        SelecttimevariablePwrMatDropDown  matlab.ui.control.DropDown
        SelecttimevariableDropDown_3Label  matlab.ui.control.Label
        SelectpowervariablePwrMatDropDown  matlab.ui.control.DropDown
        SelectpowermatrixvariableLabel  matlab.ui.control.Label
        SelectresourcedatafilePwrMatLabel  matlab.ui.control.Label
        BrowsePwrMatButton              matlab.ui.control.Button
        ResourceDataSeaStatePanel       matlab.ui.container.Panel
        SelectSeaStateDropDown          matlab.ui.control.DropDown
        SelectSeaStateLabel             matlab.ui.control.Label
        ResourceDataMeanWavePanel       matlab.ui.container.Panel
        EntermeanwaveenergyperiodsEditField  matlab.ui.control.NumericEditField
        EntermeanwaveenergyperiodsEditField_2Label  matlab.ui.control.Label
        EntermeansignificantwaveheightmEditField  matlab.ui.control.NumericEditField
        EntermeansignificantwaveheightmEditField_2Label  matlab.ui.control.Label
        ResourceDataMeanPowerGenPanel   matlab.ui.container.Panel
        EntermeanpowergenerationWEditField  matlab.ui.control.NumericEditField
        EntermeanpowergenerationWEditField_2Label_2  matlab.ui.control.Label
        ResourceDataPwrTimeSeriesUploadPanel  matlab.ui.container.Panel
        DatafilePwrTextArea             matlab.ui.control.TextArea
        DatafileTextAreaLabel_3         matlab.ui.control.Label
        VariableSelectionPwrData        matlab.ui.container.Panel
        SelecttimevariableDropDown      matlab.ui.control.DropDown
        SelecttimevariableLabel         matlab.ui.control.Label
        SelectpowervariableDropDown     matlab.ui.control.DropDown
        SelectpowervariableDropDownLabel  matlab.ui.control.Label
        SelectresourcedatafilePwrLabel  matlab.ui.control.Label
        BrowsePwrButton                 matlab.ui.control.Button
        LimitMaxAUVFleetSizeEditField   matlab.ui.control.NumericEditField
        LimitMaxAUVFleetSizeCheckBox    matlab.ui.control.CheckBox
        StaggerAUVDeploymentsCheckBox   matlab.ui.control.CheckBox
        HardwareSpecsPanel              matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        WECTab                          matlab.ui.container.Tab
        BatteryEfficiency0XXWECEditField  matlab.ui.control.NumericEditField
        BatteryEfficiency0XXLabel       matlab.ui.control.Label
        GeneratorEfficiency0XXEditField  matlab.ui.control.NumericEditField
        GeneratorEfficiency0XXEditFieldLabel  matlab.ui.control.Label
        HydrodynamicEfficiency0XXEditField  matlab.ui.control.NumericEditField
        HydrodynamicEfficiency0XXEditFieldLabel  matlab.ui.control.Label
        BatteryCapacityWhWECEditField   matlab.ui.control.NumericEditField
        BatteryCapacityWhEditFieldLabel  matlab.ui.control.Label
        HotelLoadWWECEditField          matlab.ui.control.NumericEditField
        HotelLoadWEditFieldLabel        matlab.ui.control.Label
        CharacteristicDimensionmEditField  matlab.ui.control.NumericEditField
        CharacteristicDimensionmEditFieldLabel  matlab.ui.control.Label
        ModelNameWECEditField           matlab.ui.control.EditField
        ModelNameEditFieldLabel         matlab.ui.control.Label
        AUVTab                          matlab.ui.container.Tab
        SaveAUVButton                   matlab.ui.control.Button
        ConfiguredAUVsPanel             matlab.ui.container.Panel
        savedAUVdisplay                 matlab.ui.control.TextArea
        ModelNameAUVDropdown            matlab.ui.control.DropDown
        ModelNameLabel                  matlab.ui.control.Label
        MasskgEditField                 matlab.ui.control.NumericEditField
        BatteryCapacityWhAUVEditField   matlab.ui.control.NumericEditField
        MissionTimehrEditField          matlab.ui.control.NumericEditField
        MissionBatteryUsed0XXEditField  matlab.ui.control.NumericEditField
        HotelLoadWAUVEditField          matlab.ui.control.NumericEditField
        ChargeRateWEditField            matlab.ui.control.NumericEditField
        ChargingSwitch                  matlab.ui.control.Switch
        BatteryRechargeThreshold0XXEditField  matlab.ui.control.NumericEditField
        BatteryRechargeThreshold0XXEditFieldLabel  matlab.ui.control.Label
        BatteryEfficiency0XXAUVEditField  matlab.ui.control.NumericEditField
        BatteryEfficiency0XXEditField_2Label  matlab.ui.control.Label
        PowerTransferEfficiency0XXEditField  matlab.ui.control.NumericEditField
        PowerTransferEfficiency0XXEditFieldLabel  matlab.ui.control.Label
        HotelLoadWEditField_2Label      matlab.ui.control.Label
        ChargingSwitchLabel             matlab.ui.control.Label
        ChargeRateWEditFieldLabel       matlab.ui.control.Label
        BatteryCapacityWhEditField_2Label  matlab.ui.control.Label
        MissionBatteryUsed0XXEditFieldLabel  matlab.ui.control.Label
        MissionTimehrEditFieldLabel     matlab.ui.control.Label
        MasskgEditFieldLabel            matlab.ui.control.Label
        EnergyStorageTab                matlab.ui.container.Tab
        AUVDockHotelLoadEditField       matlab.ui.control.NumericEditField
        AUVDockHotelLoadEditFieldLabel  matlab.ui.control.Label
        BaseHotelLoadEditField          matlab.ui.control.NumericEditField
        BaseHotelLoadEditFieldLabel     matlab.ui.control.Label
        PredefinedCentralBatteryCapacityWhEditField  matlab.ui.control.NumericEditField
        PredefinedCentralBatteryCapacityWhCheckBox  matlab.ui.control.CheckBox
        WECPowerTransferEfficiency0XXEditField  matlab.ui.control.NumericEditField
        WECPowerTransferEfficiency0XXEditFieldLabel  matlab.ui.control.Label
        AUVDockPowerTransferEfficiency0XXEditField  matlab.ui.control.NumericEditField
        AUVDockPowerTransferEfficiency0XXEditFieldLabel  matlab.ui.control.Label
        BatteryEfficiency0XXESEditField  matlab.ui.control.NumericEditField
        BatteryEfficiency0XXEditField_3Label  matlab.ui.control.Label
        OutputPlotsPanel                matlab.ui.container.Panel
        GridLayoutOutputPlots           matlab.ui.container.GridLayout
        AggregateWorkingHoursvsDependentVariableCheckBox  matlab.ui.control.CheckBox
        AUVBatteryLevelsvsTimeCheckBox  matlab.ui.control.CheckBox
        CentralBatteryLevelvsTimeCheckBox  matlab.ui.control.CheckBox
        PowerGenerationvsTimeCheckBox   matlab.ui.control.CheckBox
        Image_4                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        Defaults % Struct containing default startup values for all user-editable fields
        auvDefaultModels  % Struct containing data for default AUV models
        dataMetadata  % Struct containing data file name, path, and variable metadata from user-selected *.mat data file
        successfulBrowse % 'true' if user selected a file after prompting 'browse', 'false' if no file selected
        warnings  % Container class containing warnings
        auv AUV % AUV array containing user-input AUV properties

        resourceDataPanels  %  All resource data panels within the simulation settings panel 
        simSettingsvarSelectionPanels  % All the variable selection panels within the simulation settings panel 
        inputDataFileDisplays  % All of the input data file displays within the simulation settings panel
        allVarDropdowns  % All data variable selection dropdowns in the simulation settings panel
    end
    
    methods (Access = private)
        
        % Calculates AUV recharge efficiency according to charging method
        function getPowerTransferEfficiency(app)
            rechargeMethod = app.ChargingSwitch.Value;  % 1: wired, 0: wireless
            if rechargeMethod  % wired
                app.PowerTransferEfficiency0XXEditField.Value = 0.9;
            else  % wireless
                app.PowerTransferEfficiency0XXEditField.Value = 0.5;
            end
        end
        
        % Updates app visuals and interactivity given current checkbox and 
        % dropdown values
        function updateVisuals(app)
            % Update EditField ability from checkboxes 
            if app.LimitMaxAUVFleetSizeCheckBox.Value  % if box checked
                app.LimitMaxAUVFleetSizeEditField.Enable = true; 
            else
                app.LimitMaxAUVFleetSizeEditField.Enable = false;
                app.LimitMaxAUVFleetSizeEditField.Value = [];
            end
            if app.PredefinedCentralBatteryCapacityWhCheckBox.Value  % if box checked
                app.PredefinedCentralBatteryCapacityWhEditField.Enable = true;
            else
                app.PredefinedCentralBatteryCapacityWhEditField.Enable = false;
                app.PredefinedCentralBatteryCapacityWhEditField.Value = [];
            end

            % Update 'Resource Data' panel interactivity
            switch app.ResourceDataTypeDropDown.Value
                case 1  % 'Sea State Power Data'
                    switch app.DependentVariableDropDown.Value
                        case 1  % 'AUV Model'
                            app.enableResourceDataPanel(app.ResourceDataSeaStatePanel);

                        case 2  % WEC Power Gen. / Wave Resource
                            app.enableResourceDataPanel([])
                    end

                case 2  %'Power Gen. (Time Series)'
                    app.enableResourceDataPanel(app.ResourceDataPwrTimeSeriesUploadPanel);

                case 3  %'Power Gen. (Mean Value)'
                    app.enableResourceDataPanel(app.ResourceDataMeanPowerGenPanel);

                case 4  %'Wave Specs. (Time Series)'
                    app.enableResourceDataPanel(app.ResourceDataWaveTimeSeriesUploadPanel);

                case 5  %'Wave Specs. (Mean Value)'
                    app.enableResourceDataPanel(app.ResourceDataMeanWavePanel);

                case 6  %'Power Matrix with Time Series'
                    app.enableResourceDataPanel(app.ResourceDataPwrMatTimeSeriesUploadPanel);
            end  

            % Reset dropdown variable warnings
            k = keys(app.warnings);
            dropdownKeys = k(~strcmp(k, app.SimulationTimestepsEditField.Tag));
            for i = 1:numel(dropdownKeys)
                app.warnings(k{i}) = '';
            end
            app.refreshWarningDisplay();
        end
        
        function changeFieldTextColor(app, field, color) 
            arguments
                app  %#ok
                field
                color = [0, 0, 0]  % defaults to black if not provided
            end

            field.FontColor = color;
        end
        
        function enableResourceDataPanel(app, panel)
            % Disable and hide all resource data panels
            set(app.resourceDataPanels, 'Enable' , false);
            set(app.resourceDataPanels, 'Visible', false);

            % Reset app.dataMetadata property, file display, variable selection panels & csv checkbox (if applicable)
            app.resetDataMetadata();
            % allVarDropdowns = findall(app.simSettingsvarSelectionPanels, '-property', 'Items');
            set(app.simSettingsvarSelectionPanels, 'Enable', false);
            set(app.simSettingsvarSelectionPanels, 'Visible', false);
            set(app.inputDataFileDisplays, 'Value', app.dataMetadata.fileName);
            set(app.allVarDropdowns, 'Items', app.dataMetadata.variables);
            if ~isequal(app.DataisacsvtablecontainingtherequiredheadersCheckBox.Value, app.Defaults.DataisacsvtablecontainingtherequiredheadersCheckBox)
                app.DataisacsvtablecontainingtherequiredheadersCheckBox.Value = app.Defaults.DataisacsvtablecontainingtherequiredheadersCheckBox;
            end

            % Enable given panel and its non-variable-selection children
            if ~isempty(panel)
                panel.Enable = true; 
                panel.Visible = true; 

                % ID given panel's variable selection panel
                varSelectionPanel = findall(panel, 'Tag', 'VariableSelectionPanel');

                % Enable all children except the variable selection panel & contents
                allChildren = findall(panel, '-property', 'Enable'); 
                varSelectChildren = findall(varSelectionPanel, '-property', 'Enable');
                enableChildren = allChildren(~ismember(allChildren, [varSelectionPanel; varSelectChildren]));
                set(enableChildren, 'Enable', true);
                set(enableChildren, 'Visible', true);

                % Keep variable selection panel visible and disabled until a successful browse
                if ~isempty(varSelectionPanel)
                    varSelectionPanel.Enable = false; 
                    varSelectionPanel.Visible = true;
                end
            end
        end

        % Save default AUV model specs under the name 'models'
        function models = loadAUVModels(app) %#ok
            models = struct();
            models(1).name = 'A';   models(1).mass = 27;     models(1).batteryCapacity = 800;     models(1).missionTime = 8*0.8;       models(1).missionBatteryUsed = 0.8;   models(1).hotelLoad = 90;                                         models(1).chargeRate = 160;          models(1).chargingMethod = 1;   models(1).rechargeThreshold = 0.2;  models(1).batteryEfficiency = 0.9;  
            models(2).name = 'B';   models(2).mass = 38.5;   models(2).batteryCapacity = 800;     models(2).missionTime = 14*0.8;      models(2).missionBatteryUsed = 0.8;   models(2).hotelLoad = 90;                                         models(2).chargeRate = 160;          models(2).chargingMethod = 1;   models(2).rechargeThreshold = 0.2;  models(2).batteryEfficiency = 0.9; 
            models(3).name = 'C';   models(3).mass = 38.6;   models(3).batteryCapacity = 1500;    models(3).missionTime = 10*0.8;      models(3).missionBatteryUsed = 0.8;   models(3).hotelLoad = 90;                                         models(3).chargeRate = 1500/6;       models(3).chargingMethod = 1;   models(3).rechargeThreshold = 0.2;  models(3).batteryEfficiency = 0.9;  
            models(4).name = 'D';   models(4).mass = 58.5;   models(4).batteryCapacity = 3000;    models(4).missionTime = 20*0.8;      models(4).missionBatteryUsed = 0.8;   models(4).hotelLoad = round(models(4).mass / 25);                 models(4).chargeRate = 3000/6;       models(4).chargingMethod = 1;   models(4).rechargeThreshold = 0.2;  models(4).batteryEfficiency = 0.9; 
            models(5).name = 'E';   models(5).mass = 70.3;   models(5).batteryCapacity = 4500;    models(5).missionTime = 30*0.8;      models(5).missionBatteryUsed = 0.8;   models(5).hotelLoad = round(models(5).mass / 25);                 models(5).chargeRate = 4500/6;       models(5).chargingMethod = 1;   models(5).rechargeThreshold = 0.2;  models(5).batteryEfficiency = 0.9; 
            models(6).name = 'F';   models(6).mass = 210;    models(6).batteryCapacity = 9600;    models(6).missionTime = 42*0.8;      models(6).missionBatteryUsed = 0.8;   models(6).hotelLoad = round(models(6).mass / 25);                 models(6).chargeRate = 9600/8;       models(6).chargingMethod = 1;   models(6).rechargeThreshold = 0.2;  models(6).batteryEfficiency = 0.9; 
            models(7).name = 'G';   models(7).mass = 279;    models(7).batteryCapacity = 19300;   models(7).missionTime = 80*0.8;      models(7).missionBatteryUsed = 0.8;   models(7).hotelLoad = round(models(7).mass / 25);                 models(7).chargeRate = 19300/10;     models(7).chargingMethod = 1;   models(7).rechargeThreshold = 0.2;  models(7).batteryEfficiency = 0.9; 
            models(8).name = 'H';   models(8).mass = 347;    models(8).batteryCapacity = 28900;   models(8).missionTime = 110*0.8;     models(8).missionBatteryUsed = 0.8;   models(8).hotelLoad = round(models(8).mass / 25);                 models(8).chargeRate = 28900/12;     models(8).chargingMethod = 1;   models(8).rechargeThreshold = 0.2;  models(8).batteryEfficiency = 0.9; 
            models(9).name = 'I';   models(9).mass = 1630;   models(9).batteryCapacity = 17550;   models(9).missionTime = 25*0.8;      models(9).missionBatteryUsed = 0.8;   models(9).hotelLoad = round(models(9).mass / 25);                 models(9).chargeRate = 17550/24;     models(9).chargingMethod = 1;   models(9).rechargeThreshold = 0.2;  models(9).batteryEfficiency = 0.9;  
            models(10).name = 'J';  models(10).mass = 70;    models(10).batteryCapacity = 1900;   models(10).missionTime = 8*0.8;      models(10).missionBatteryUsed = 0.8;  models(10).hotelLoad = round(models(10).mass / 25);               models(10).chargeRate = 1900/6;      models(10).chargingMethod = 1;  models(10).rechargeThreshold = 0.2;  models(10).batteryEfficiency = 0.9; 
            models(11).name = 'K';  models(11).mass = 250;   models(11).batteryCapacity = 7600;   models(11).missionTime = 36*0.8;     models(11).missionBatteryUsed = 0.8;  models(11).hotelLoad = round(models(11).mass / 25);               models(11).chargeRate = 7600/6;      models(11).chargingMethod = 1;  models(11).rechargeThreshold = 0.2;  models(11).batteryEfficiency = 0.9;
            models(12).name = 'L';  models(12).mass = 750;   models(12).batteryCapacity = 13500;  models(12).missionTime = 25*0.8;     models(12).missionBatteryUsed = 0.8;  models(12).hotelLoad = round(models(12).mass / 25);               models(12).chargeRate = 13500/6;     models(12).chargingMethod = 1;  models(12).rechargeThreshold = 0.2;  models(12).batteryEfficiency = 0.9; 
            models(13).name = 'M';  models(13).mass = 72.6;  models(13).batteryCapacity = 1500;   models(13).missionTime = 3.5*0.8;    models(13).missionBatteryUsed = 0.8;  models(13).hotelLoad = round(models(13).mass / 25);               models(13).chargeRate = 1500/6;      models(13).chargingMethod = 1;  models(13).rechargeThreshold = 0.2;  models(13).batteryEfficiency = 0.9;  
            models(14).name = 'N';  models(14).mass = 2200;  models(14).batteryCapacity = 62500;  models(14).missionTime = 72*0.8;     models(14).missionBatteryUsed = 0.8;  models(14).hotelLoad = round(models(14).mass / 25);               models(14).chargeRate = 62500/8;     models(14).chargingMethod = 1;  models(14).rechargeThreshold = 0.2;  models(14).batteryEfficiency = 0.9;
            models(15).name = 'O';  models(15).mass = 8000;  models(15).batteryCapacity = 400000; models(15).missionTime = 360*0.8;    models(15).missionBatteryUsed = 0.8;  models(15).hotelLoad = round(models(15).mass / 25);               models(15).chargeRate = 400000/8;    models(15).chargingMethod = 1;  models(15).rechargeThreshold = 0.2;  models(15).batteryEfficiency = 0.9;  
            models(16).name = 'P';  models(16).mass = 1000;  models(16).batteryCapacity = 24000;  models(16).missionTime = 24*0.8;     models(16).missionBatteryUsed = 0.8;  models(16).hotelLoad = round(models(16).mass / 25);               models(16).chargeRate = 24000/8;     models(16).chargingMethod = 1;  models(16).rechargeThreshold = 0.2;  models(16).batteryEfficiency = 0.9; 
            models(17).name = 'Q';  models(17).mass = 1550;  models(17).batteryCapacity = 48000;  models(17).missionTime = 74*0.8;     models(17).missionBatteryUsed = 0.8;  models(17).hotelLoad = round(models(17).mass / 25);               models(17).chargeRate = 48000/8;     models(17).chargingMethod = 1;  models(17).rechargeThreshold = 0.2;  models(17).batteryEfficiency = 0.9;  
            models(18).name = 'R';  models(18).mass = 25;    models(18).batteryCapacity = 600;    models(18).missionTime = 10*0.8;     models(18).missionBatteryUsed = 0.8;  models(18).hotelLoad = 1;                                         models(18).chargeRate = 600/5;       models(18).chargingMethod = 1;  models(18).rechargeThreshold = 0.2;  models(18).batteryEfficiency = 0.9;  
            models(19).name = 'S';  models(19).mass = 28;    models(19).batteryCapacity = 600;    models(19).missionTime = 10*0.8;     models(19).missionBatteryUsed = 0.8;  models(19).hotelLoad = round(models(19).mass / 25);               models(19).chargeRate = 600/4;       models(19).chargingMethod = 0;  models(19).rechargeThreshold = 0.2;  models(19).batteryEfficiency = 0.9; 
            models(20).name = 'T';  models(20).mass = 650;   models(20).batteryCapacity = 12000;  models(20).missionTime = 10.8*0.8;   models(20).missionBatteryUsed = 0.8;  models(20).hotelLoad = round(models(20).mass / 25);               models(20).chargeRate = 12000/3.64;  models(20).chargingMethod = 1;  models(20).rechargeThreshold = 0.2;  models(20).batteryEfficiency = 0.9;  
            models(21).name = 'U';  models(21).mass = 1300;  models(21).batteryCapacity = 30000;  models(21).missionTime = 21.6*0.8;   models(21).missionBatteryUsed = 0.8;  models(21).hotelLoad = round(models(21).mass / 25);               models(21).chargeRate = 30000/9.09;  models(21).chargingMethod = 1;  models(21).rechargeThreshold = 0.2;  models(21).batteryEfficiency = 0.9;  
        end
        
        % applyAUVModelSpecs fills AUV tab inputs with default values
        % paired with the selection of a default AUV (A-U). Will throw an
        % error if called when the chosen model is not a default value.
        function applyAUVModelSpecs(app)
            modelVal = str2double(app.ModelNameAUVDropdown.Value);
            
            app.MasskgEditField.Value = app.auvDefaultModels(modelVal).mass;
            app.BatteryCapacityWhAUVEditField.Value = app.auvDefaultModels(modelVal).batteryCapacity;
            app.MissionTimehrEditField.Value = app.auvDefaultModels(modelVal).missionTime;
            app.MissionBatteryUsed0XXEditField.Value = app.auvDefaultModels(modelVal).missionBatteryUsed;
            app.HotelLoadWAUVEditField.Value = app.auvDefaultModels(modelVal).hotelLoad;
            app.ChargeRateWEditField.Value = app.auvDefaultModels(modelVal).chargeRate;
            app.ChargingSwitch.Value = app.auvDefaultModels(modelVal).chargingMethod;  % 1: wired, 0: wireless
            app.BatteryRechargeThreshold0XXEditField.Value = app.auvDefaultModels(modelVal).rechargeThreshold;
            app.BatteryEfficiency0XXAUVEditField.Value = app.auvDefaultModels(modelVal).batteryEfficiency;

            % Set power transfer efficiency based on charging method
            app.getPowerTransferEfficiency();

            % Set all fields to gray text 
            auvFields = [app.ModelNameAUVDropdown, app.MasskgEditField, app.BatteryCapacityWhAUVEditField, app.MissionTimehrEditField, app.MissionBatteryUsed0XXEditField, app.HotelLoadWAUVEditField, app.ChargeRateWEditField, app.BatteryRechargeThreshold0XXEditField, app.BatteryEfficiency0XXAUVEditField, app.PowerTransferEfficiency0XXEditField];
            set(auvFields, 'FontColor', [0.5, 0.5, 0.5]);
        end
        
        % Prompts user for file selection, uploads data, and extracts
        % variable names. If multiselect is 'on', requires single file
        % selection. If allowCSV is enabled (true), browses for *.csv
        % filetypes, otherwise browses for *.mat files. Outputs
        % whether or not 'browse' was successful under
        % app.successfulBrowse, and data file name, path, and variables
        % under app.dataMetadata.
        function extractDataInfo(app, multiSelect, allowCSV) 
            arguments
                app
                multiSelect (1,1) string {mustBeMember(multiSelect, {'on', 'off'})} = 'off'  % optional, defaults to 'off'
                allowCSV = false  % optional, defaults to false
            end

            % Prompt user to browse for data file, extract file path
            if allowCSV
                [fileName, filePath] = uigetfile('*.csv', 'Select *.csv data file(s)', 'MultiSelect', multiSelect);
            else
                [fileName, filePath] = uigetfile('*.mat', 'Select *.mat data file(s)', 'MultiSelect', multiSelect);
            end

            % Track if browse was successful... 
            if ~filePath
                % Browse unsuccessful, do nothing.
                app.successfulBrowse = false;  
            else
                % Browse successful, save variable metadata and file path
                app.successfulBrowse = true; 

                if ~allowCSV
                    % Get variable metadata of *.mat file
                    if ischar(fileName)
                        fileName = {fileName};
                    end
                    fileContents = whos('-file', fullfile(filePath, fileName{1}));
        
                    % Save variable metadata to app
                    app.dataMetadata.variables = {fileContents.name};
                    app.dataMetadata.variableSizes = {fileContents.size};
                end

                % Save file name and path
                app.dataMetadata.path = filePath;
                app.dataMetadata.fileName = fileName;
            end
        end
        
        function resetDataMetadata(app)
            app.dataMetadata.variables = {};
            app.dataMetadata.variableSizes = {};
            app.dataMetadata.path      = [];
            app.dataMetadata.fileName  = '';
        end
        
        % Evaluates the given variable's size against a given expectation,
        % or the default expected size of [nx1]. Displays a warning to the
        % GUI if variable is incorrectly sized.
        function warningMsg = evalVariableSize(app, variableName, variableSize, expectedRows, expectedCols)
            arguments
                app  %#ok
                variableName (1,1) string  % Name of variable
                variableSize (1,2) {mustBeNumeric, mustBePositive, mustBeInteger} % size of variable as a [1x2] numeric array
                expectedRows (1,1) {mustBeNumeric, mustBeNonnegative, mustBeInteger} = 0  % Expected variable rows, with 0 indicating any number.
                expectedCols (1,1) {mustBeNumeric, mustBeNonnegative, mustBeInteger} = 1  % Expected variable columns, with 0 indicating any number. 
            end
    
            % Read variable size
            rows = variableSize(1); 
            cols = variableSize(2); 
            
            % Compare variable size against expectation
            rowMatch = (expectedRows == 0) || (rows == expectedRows);
            colMatch = (expectedCols == 0) || (cols == expectedCols);

            rowMatchT = (expectedCols == 0) || (rows == expectedCols);
            colMatchT = (expectedRows == 0) || (cols == expectedRows); 

            inputIsVector = (rows == 1) || (cols == 1);
            matrixExpected = (expectedRows == 0) && (expectedCols == 0);
            matrixNotAVector = ~(matrixExpected && inputIsVector);  % Fail if matrix is expected, but vector is given

            validSize = ((rowMatch && colMatch) || (rowMatchT && colMatchT)) && matrixNotAVector; 

            if ~validSize
                if expectedRows == 0
                    rowText = 'm';
                else
                    rowText = num2str(expectedRows);
                end
                if expectedCols == 0
                    colText = 'n';
                else
                    colText = num2str(expectedCols);
                end

                warningMsg = sprintf('WARNING: %s is an unexpected size [%d x %d] (expecting [%s x %s]). This may cause computational error.', variableName, rows, cols, rowText, colText);
            else
                warningMsg = '';
            end            
        end
        
        % Displays active warnings. 
        function refreshWarningDisplay(app)
            activeWarnings = {};
            k = keys(app.warnings);
            for i = 1:numel(k)
                if ~isempty(app.warnings(k{i}))
                    activeWarnings{end+1} = app.warnings(k{i});
                end
            end

            if isempty(activeWarnings)
                app.WarningTextArea.Value = ''; 
            else
                app.WarningTextArea.Value = activeWarnings;
            end
        end
        
        % Checks resource data dropdown variable sizes and throws a warning if vectors selected are different sizes 
        function checkVectorLengths(app, parentPanel)
            % Get all variable selection dropdown values and sizes in the active panel
            varSelectionPanel = findall(parentPanel, 'Tag', 'VariableSelectionPanel');
            dropdowns = findall(varSelectionPanel, 'Type', 'uidropdown');
            dropdowns = dropdowns(~strcmp({dropdowns.Tag}, 'pwrMatrixVar'));  % Exclude power matrix variable from comparison (not a vector)

            % Get all variable lengths
            lengths = [];
            for i = 1:numel(dropdowns)
                varName = dropdowns(i).Value;
                if ~isempty(varName) 
                    varIndx = strcmp(app.dataMetadata.variables, varName);
                    if any(varIndx) 
                        varSize = app.dataMetadata.variableSizes{varIndx};
                        lengths(end+1) = max(varSize);
                    end
                end
            end

            % Check if lengths match, and trigger a warning if not
            if numel(unique(lengths)) > 1
                app.warnings('vectorLengthMismatch') = sprintf('WARNING: Selected variables have mismatched lengths [%s]. This may cause computational error.', num2str(unique(lengths)));
            else
                app.warnings('vectorLengthMismatch') = '';
            end
        end
        
        % Builds AUV class object from user-inputs
        function auv = compileAUV(app)
            auv = AUV(app.ModelNameAUVDropdown.Items{str2double(app.ModelNameAUVDropdown.Value)}, app.MasskgEditField.Value, ...
                app.BatteryCapacityWhAUVEditField.Value, app.MissionTimehrEditField.Value, ...
                app.MissionBatteryUsed0XXEditField.Value, app.HotelLoadWAUVEditField.Value, ...
                app.ChargeRateWEditField.Value, app.ChargingSwitch.Value, ...
                app.BatteryRechargeThreshold0XXEditField.Value, app.BatteryEfficiency0XXAUVEditField.Value, ...
                app.PowerTransferEfficiency0XXEditField.Value);
        end

        % Builds WEC class object from user-inputs
        function wec = compileWEC(app)
            wec = WEC(app.ModelNameWECEditField.Value, app.CharacteristicDimensionmEditField.Value, ...
                app.BatteryCapacityWhWECEditField.Value, app.HotelLoadWWECEditField.Value, ...
                app.HydrodynamicEfficiency0XXEditField.Value, app.GeneratorEfficiency0XXEditField.Value, ...
                app.BatteryEfficiency0XXWECEditField.Value);
        end

        % Builds EnergyStorage class object from user-inputs
        function energyStorage = compileEnergyStorage(app)
            if app.PredefinedCentralBatteryCapacityWhCheckBox.Value
                batteryCapacity = app.PredefinedCentralBatteryCapacityWhEditField.Value; 
            else
                batteryCapacity = 0;  % Signals EnergyStorage to calculate its own size
            end

            energyStorage = EnergyStorage(batteryCapacity, app.BaseHotelLoadEditField.Value, ...
                app.AUVDockHotelLoadEditField.Value, ...
                app.BatteryEfficiency0XXESEditField.Value, app.WECPowerTransferEfficiency0XXEditField.Value, ...
                app.AUVDockPowerTransferEfficiency0XXEditField.Value);
        end

        % Builds ModelInput class object from user-inputs
        function modIn = compileModelInput(app)
            depVar = app.DependentVariableDropDown.Items{app.DependentVariableDropDown.Value};
            resourceDataType = app.ResourceDataTypeDropDown.Value;
            outputPlots.pGen = app.PowerGenerationvsTimeCheckBox.Value; 
            outputPlots.centBatteryvtime = app.CentralBatteryLevelvsTimeCheckBox.Value;
            outputPlots.auvBatteryvtime = app.AUVBatteryLevelsvsTimeCheckBox.Value;
            outputPlots.aggWorkingHoursvDepVar = app.AggregateWorkingHoursvsDependentVariableCheckBox.Value;

            % Get all resource data variables
            switch app.ResourceDataTypeDropDown.Value
                case 1  % Sea State
                    resourceDataVariables.seaState = app.SelectSeaStateDropDown.Value;

                case 2  % Power gen (time series)
                    dataFiles = fullfile(app.dataMetadata.path, app.dataMetadata.fileName);
                    resourceDataVariables.tVarName = app.SelecttimevariableDropDown.Value;
                    resourceDataVariables.pwrVarName = app.SelectpowervariableDropDown.Value;
                    if ischar(dataFiles) || isstring(dataFiles)
                        dataFiles = {dataFiles}; 
                    end
                    resourceDataVariables.dataFiles = dataFiles;

                case 3  % Power gen (mean value)
                    resourceDataVariables.meanPower = app.EntermeanpowergenerationWEditField.Value;

                case 4  % Wave specs (time series)
                    dataFiles = fullfile(app.dataMetadata.path, app.dataMetadata.fileName);
                    if ischar(dataFiles) || isstring(dataFiles)
                        dataFiles = {dataFiles}; 
                    end
                    resourceDataVariables.dataFiles = dataFiles;

                case 5  % wave specs (mean value)
                    sigWaveHeight = app.EntermeansignificantwaveheightmEditField.Value;
                    energyPeriod = app.EntermeanwaveenergyperiodsEditField.Value;
                    peakPeriod = energyPeriod;  % Simplifying user-input
                    resourceDataVariables.waveSpecTable = table(sigWaveHeight', energyPeriod', peakPeriod','VariableNames',{'SignificantWaveHeight','EnergyPeriod','PeakPeriod'});  % Current UI doesn't allow for vector inputs, so transpose doesn't do anything (yet)

                case 6  % power matrix w/ time series
                    dataFiles = fullfile(app.dataMetadata.path, app.dataMetadata.fileName);
                    resourceDataVariables.pMatVarName = app.SelectpowervariablePwrMatDropDown.Value;
                    resourceDataVariables.tVarName = app.SelecttimevariablePwrMatDropDown.Value; 
                    resourceDataVariables.HsVarName = app.SelectsignificantwaveheightPwrMatDropDown.Value;
                    resourceDataVariables.TeVarName = app.SelectwaveenergyperiodPwrMatDropDown.Value;
                    if ischar(dataFiles) || isstring(dataFiles)
                        dataFiles = {dataFiles}; 
                    end
                    resourceDataVariables.dataFiles = dataFiles;
            end

            switch depVar
                case 'AUV Model'
                    auvModels = {app.auv.model};  % cell array of all saved model names
                otherwise
                    auvModels = app.auv(1).model;  % single string
            end
            if app.LimitMaxAUVFleetSizeCheckBox.Value
                maxFleetSize = app.LimitMaxAUVFleetSizeEditField.Value;
            else
                maxFleetSize = 0;
            end

            modIn = ModelInput(app.SimulationDurationhrEditField.Value, depVar, ...
                resourceDataType, app.StaggerAUVDeploymentsCheckBox.Value, ...
                maxFleetSize, app.PredefinedCentralBatteryCapacityWhEditField.Value, ...
                app.SimulationTimestepsEditField.Value, auvModels, resourceDataVariables, outputPlots);
        end
        
        % Compiles all user-input settings into AUV, WEC, EnergyStorage,
        % and ModelInput class objects
        function [auv, wec, energyStorage, modelInput] = collectUIsettings(app)
            auv = app.auv; % AUV settings already compiled
            wec = app.compileWEC();
            energyStorage = app.compileEnergyStorage();
            modelInput = app.compileModelInput();
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add subfolders to path
            addpath('Component Classes');
            addpath('Functions');
            addpath('InputData');

            % Set default AUV options
            app.auvDefaultModels = app.loadAUVModels(); 
            
            % Set default fields & switches
            % 'Simulation Settings' panel
            app.Defaults.SimulationDurationhrEditField = 14*24;
            app.Defaults.SimulationTimestepsEditField = 30; 
            app.Defaults.StaggerAUVDeploymentsCheckBox = true; 
            app.Defaults.LimitMaxAUVFleetSizeCheckBox = false;
            app.Defaults.LimitMaxAUVFleetSizeEditField = [];
            app.Defaults.DependentVariableDropDown = 1;  % 'AUV Model';
            app.Defaults.ResourceDataTypeDropDown = 4;  % 4-'Wave Specs (Time Series)'; 
            % Resource Data panels
            app.Defaults.SelectSeaStateDropDown = '3';
            app.Defaults.EntermeanwaveenergyperiodsEditField = 1.5;
            app.Defaults.EntermeansignificantwaveheightmEditField = 2;
            app.Defaults.EntermeanpowergenerationWEditField = 1200;
            app.Defaults.DataisacsvtablecontainingtherequiredheadersCheckBox = false;
            % 'Hardware Specifications' panel
            % WEC Tab
            app.Defaults.ModelNameWECEditField = 'Default';
            app.Defaults.CharacteristicDimensionmEditField = 1;
            app.Defaults.HotelLoadWWECEditField = 50;
            app.Defaults.BatteryCapacityWhWECEditField = 500;
            app.Defaults.HydrodynamicEfficiency0XXEditField = (1.3 * app.Defaults.CharacteristicDimensionmEditField + 5.6)/100; 
            app.Defaults.GeneratorEfficiency0XXEditField = 0.8;
            app.Defaults.BatteryEfficiency0XXWECEditField = 0.9;
            % AUV Tab
            app.Defaults.ModelNameAUVDropdown = 'A';
            app.Defaults.savedAUVdisplay = {''};
            % Energy Storage Tab
            app.Defaults.PredefinedCentralBatteryCapacityWhCheckBox = false;
            app.Defaults.PredefinedCentralBatteryCapacityWhEditField = [];
            app.Defaults.BatteryEfficiency0XXESEditField = 0.9;
            app.Defaults.WECPowerTransferEfficiency0XXEditField = 0.9;
            app.Defaults.AUVDockPowerTransferEfficiency0XXEditField = 0.9;
            app.Defaults.BaseHotelLoadEditField = 10;
            app.Defaults.AUVDockHotelLoadEditField = 20;
            % 'Output Plots' panel
            app.Defaults.PowerGenerationvsTimeCheckBox = false; 
            app.Defaults.CentralBatteryLevelvsTimeCheckBox = false;
            app.Defaults.AUVBatteryLevelsvsTimeCheckBox = false; 
            app.Defaults.AggregateWorkingHoursvsDependentVariableCheckBox = false;
            % Set values to defaults
            app.SimulationDurationhrEditField.Value = app.Defaults.SimulationDurationhrEditField; 
            app.SimulationTimestepsEditField.Value = app.Defaults.SimulationTimestepsEditField; 
            app.StaggerAUVDeploymentsCheckBox.Value = app.Defaults.StaggerAUVDeploymentsCheckBox; 
            app.LimitMaxAUVFleetSizeCheckBox.Value = app.Defaults.LimitMaxAUVFleetSizeCheckBox;
            app.LimitMaxAUVFleetSizeEditField.Value = app.Defaults.LimitMaxAUVFleetSizeEditField;
            app.DependentVariableDropDown.Value = app.Defaults.DependentVariableDropDown;  %'AUV Model';
            app.ResourceDataTypeDropDown.Value = app.Defaults.ResourceDataTypeDropDown;  %4-'Power Gen. (Time Series)'; 
            % Resource Data panels
            app.SelectSeaStateDropDown.Value = app.Defaults.SelectSeaStateDropDown;
            app.EntermeanwaveenergyperiodsEditField.Value = app.Defaults.EntermeanwaveenergyperiodsEditField;
            app.EntermeansignificantwaveheightmEditField.Value = app.Defaults.EntermeansignificantwaveheightmEditField;
            app.EntermeanpowergenerationWEditField.Value = app.Defaults.EntermeanpowergenerationWEditField;
            app.DataisacsvtablecontainingtherequiredheadersCheckBox.Value = app.Defaults.DataisacsvtablecontainingtherequiredheadersCheckBox;
            % Variable selection subpanels
            dropdowns = [findall(app.VariableSelectionWaveData, 'Type', 'uidropdown'); findall(app.VariableSelectionPwrMatData, 'Type', 'uidropdown'); findall(app.VariableSelectionPwrData, 'Type', 'uidropdown')];
            set(dropdowns, 'Items', {});
            % 'Hardware Specifications' panel
            % WEC Tab
            app.ModelNameWECEditField.Value = app.Defaults.ModelNameWECEditField;
            app.CharacteristicDimensionmEditField.Value = app.Defaults.CharacteristicDimensionmEditField;
            app.HotelLoadWWECEditField.Value = app.Defaults.HotelLoadWWECEditField;
            app.BatteryCapacityWhWECEditField.Value = app.Defaults.BatteryCapacityWhWECEditField;
            app.HydrodynamicEfficiency0XXEditField.Value = app.Defaults.HydrodynamicEfficiency0XXEditField; 
            app.GeneratorEfficiency0XXEditField.Value = app.Defaults.GeneratorEfficiency0XXEditField;
            app.BatteryEfficiency0XXWECEditField.Value = app.Defaults.BatteryEfficiency0XXWECEditField;
            % AUV Tab
            app.ModelNameAUVDropdown.Value = app.Defaults.ModelNameAUVDropdown;
            app.savedAUVdisplay.Value = app.Defaults.savedAUVdisplay;
            % call function that gets AUV values
            app.applyAUVModelSpecs()
            % Energy Storage Tab
            app.PredefinedCentralBatteryCapacityWhCheckBox.Value = app.Defaults.PredefinedCentralBatteryCapacityWhCheckBox;
            app.PredefinedCentralBatteryCapacityWhEditField.Value = app.Defaults.PredefinedCentralBatteryCapacityWhEditField;
            app.BatteryEfficiency0XXESEditField.Value = app.Defaults.BatteryEfficiency0XXESEditField;
            app.WECPowerTransferEfficiency0XXEditField.Value = app.Defaults.WECPowerTransferEfficiency0XXEditField;
            app.AUVDockPowerTransferEfficiency0XXEditField.Value = app.Defaults.AUVDockPowerTransferEfficiency0XXEditField;
            app.BaseHotelLoadEditField.Value = app.Defaults.BaseHotelLoadEditField; 
            app.AUVDockHotelLoadEditField.Value = app.Defaults.AUVDockHotelLoadEditField; 
            % 'Output Plots' panel
            app.PowerGenerationvsTimeCheckBox.Value = app.Defaults.PowerGenerationvsTimeCheckBox; 
            app.CentralBatteryLevelvsTimeCheckBox.Value = app.Defaults.CentralBatteryLevelvsTimeCheckBox;
            app.AUVBatteryLevelsvsTimeCheckBox.Value = app.Defaults.AUVBatteryLevelsvsTimeCheckBox; 
            app.AggregateWorkingHoursvsDependentVariableCheckBox.Value = app.Defaults.AggregateWorkingHoursvsDependentVariableCheckBox;

            % Disable & invisible resource data panel variable selection
            app.VariableSelectionWaveData.Enable = false; 
            app.VariableSelectionWaveData.Visible = false;
            app.VariableSelectionPwrMatData.Enable = false; 
            app.VariableSelectionPwrMatData.Visible = false;
            app.VariableSelectionPwrData.Enable = false; 
            app.VariableSelectionPwrData.Visible = false; 

            % Initialize app properties
            app.resetDataMetadata();
            app.successfulBrowse = false;

            % Generate 'warnings' container that maps to component tag 
            app.warnings = containers.Map('KeyType', 'char', 'ValueType', 'char');
            % Initialize variable dropdown and simulation timestep warnings as empty strings
            taggedComponents = findall(app.UIFigure, '-not', 'Tag', '');  
            for i = 1:numel(taggedComponents)
                tag = taggedComponents(i).Tag;
                % Exclude panel / display tags
                if ~ismember(tag, {'resourceDataPanel', 'VariableSelectionPanel', 'dataFileDisplay'})
                    app.warnings(tag) = '';
                end
            end
            % Initialize other warnings as empty strings
            app.warnings('vectorLengthMismatch') = '';

            % Initialize AUV object
            app.auv = AUV.empty();

            app.resourceDataPanels = findall(app.SimulationSettingsPanel, 'Tag', 'resourceDataPanel');
            app.simSettingsvarSelectionPanels = findall(app.SimulationSettingsPanel, 'Tag', 'VariableSelectionPanel');
            app.inputDataFileDisplays = findall(app.SimulationSettingsPanel, 'Tag', 'dataFileDisplay');
            app.allVarDropdowns = findall(app.simSettingsvarSelectionPanels, '-property', 'Items');

            
            % Enable appropriate resource data panel and editfields
            app.updateVisuals();

            % Loop through all 'edit fields' and editable dropdowns in GUI, set color to gray
            allEditFields = [findall(app.UIFigure, 'Type','uieditfield'); findall(app.UIFigure, 'Type', 'uinumericeditfield'); findall(app.UIFigure, 'Type', 'uidropdown', 'Editable', true)];
            set(allEditFields, 'FontColor', [0.5, 0.5, 0.5]);
        end

        % Value changed function: AUVDockHotelLoadEditField, 
        % ...and 25 other components
        function editFieldChanged(app, event)
            app.changeFieldTextColor(event.Source);
        end

        % Value changed function: LimitMaxAUVFleetSizeCheckBox, 
        % ...and 2 other components
        function AppModifyingValueChanged(app, event)
            app.updateVisuals();
        end

        % Value changed function: ChargingSwitch
        function ChargingSwitchValueChanged(app, event)
            % Assess charging switch value & determine respective power
            % transfer efficiency
            app.getPowerTransferEfficiency();
            app.changeFieldTextColor(app.PowerTransferEfficiency0XXEditField, [0.5, 0.5, 0.5]);
        end

        % Value changed function: ModelNameAUVDropdown
        function ModelNameAUVDropdownValueChanged(app, event)
            value = app.ModelNameAUVDropdown.Value;
            
            % If model is included in the preset dropdown list, keep gray
            % text, otherwise change text color to black.
            if ismember(value, event.Source.ItemsData)
                app.applyAUVModelSpecs();
            else
                app.changeFieldTextColor(event.Source);  % black for user-entry
            end
        end

        % Button pushed function: BrowsePwrButton, BrowsePwrMatButton, 
        % ...and 1 other component
        function BrowseButtonPushed(app, event)
            % Get file upload settings
            if app.DependentVariableDropDown.Value == 1
                multiSelect = 'off'; 
            else
                multiSelect = 'on';
            end

            allowCSV = app.DataisacsvtablecontainingtherequiredheadersCheckBox.Value;
            
            % Get data file path(s) and variable names (if *.mat)
            app.extractDataInfo(multiSelect, allowCSV);

            % If successful browse, display file, & (if *.mat) enable variable selection dropdowns 
            if app.successfulBrowse
                % Source Browse button's parent panel
                parentPanel = event.Source.Parent;

                % Display file selected
                dataFileDisplayText = findall(parentPanel, 'Type', 'uitextarea');
                set(dataFileDisplayText, 'Value', app.dataMetadata.fileName)

                if ~allowCSV
                    % Source variable selection panel to enable
                    varSelectionPanel = findall(parentPanel, 'Tag', 'VariableSelectionPanel');
                    if ~isempty(varSelectionPanel)
                        % Enable panel and dropdowns
                        varSelectionPanel.Enable = true;
                        varSelectionPanel.Visible = true;
                        dropdowns = findall(varSelectionPanel, 'Type', 'uidropdown'); 
                        set(dropdowns, 'Enable', true);
    
                        % Populate dropdowns with variable names
                        set(dropdowns, 'Items', app.dataMetadata.variables); 
                    end
                end
                
            end

            % Bring app to front
            figure(app.UIFigure);
        end

        % Value changed function: 
        % DataisacsvtablecontainingtherequiredheadersCheckBox
        function csvCheckBoxValueChanged(app, event)
            % Note: the csv checkbox only exists on the 'wave time series' resource data panel, so only that panel's properties need to be reset here    
            % Clear app.dataMetadata property
                app.resetDataMetadata();

                % Set variable dropdown selections to empty & Disable
                dropdowns = findall(app.VariableSelectionWaveData, 'Type', 'uidropdown'); 
                set(dropdowns, 'Items', app.dataMetadata.variables);
                app.VariableSelectionWaveData.Enable = false; 

                % Clear displayed data file
                app.DatafileWaveTextArea.Value = ''; 
            
        end

        % Value changed function: SelectpeakperiodvariableDropDown, 
        % ...and 8 other components
        function VectorVariableDropDownValueChanged(app, event)
            % Get dropdown clicked and its value
            parentDropdown = event.Source;
            varName = parentDropdown.Value; 

            % Get actual variable size
            varIndx = strcmp(app.dataMetadata.variables, varName);
            varSize = app.dataMetadata.variableSizes{varIndx};

            % Check variable size against expected
            app.warnings(parentDropdown.Tag) = app.evalVariableSize(varName, varSize, 0, 1);

            % Check variable lengths against other variables
            parentPanel = event.Source.Parent;
            app.checkVectorLengths(parentPanel); 

            % Refresh warning display
            app.refreshWarningDisplay();
        end

        % Value changed function: SelectpowervariablePwrMatDropDown
        function MatrixVariableDropDownValueChanged(app, event)
            parentDropdown = event.Source; 
            varName = parentDropdown.Value;
            
            % Get actual variable size
            varIndx = strcmp(app.dataMetadata.variables, varName);
            varSize = app.dataMetadata.variableSizes{varIndx};

            % Check variable size against expected
            app.warnings(parentDropdown.Tag) = app.evalVariableSize(varName, varSize, 0, 0);

            % Refresh warning display
            app.refreshWarningDisplay();
        end

        % Value changed function: SimulationTimestepsEditField
        function SimulationTimestepsEditFieldValueChanged(app, event)
            value = app.SimulationTimestepsEditField.Value;
            
            % Update edited text color to black
            app.changeFieldTextColor(event.Source);

            % Display warning if timestep is large
            if value > 300
                app.warnings(app.SimulationTimestepsEditField.Tag) = sprintf('WARNING: Simulation timestep of %d s is large and may cause fidelity issues.', value);
            else
                app.warnings(app.SimulationTimestepsEditField.Tag) = '';
            end
            app.refreshWarningDisplay();
        end

        % Value changed function: DependentVariableDropDown
        function DependentVariableDropDownValueChanged(app, event)
            % Reset Resource Data Tab
            app.updateVisuals();

            % Reset AUV Tab
            app.ModelNameAUVDropdown.Value = app.Defaults.ModelNameAUVDropdown;
            app.applyAUVModelSpecs();
            app.savedAUVdisplay.Value = app.Defaults.savedAUVdisplay;

            % Reset app.auv object
            app.auv = AUV.empty(); 
        end

        % Button pushed function: SaveAUVButton
        function SaveAUVButtonPushed(app, event)
            % Displays 'saved' AUV in sidebar, saves AUV settings to an AUV
            % class object (app.auv), and resets AUV panel to default
            % settings. Allows multiple configured AUVs if Dependent
            % Variable == AUV Model, otherwise only allows one 'configured
            % AUV'
            % Get AUV model name
            auvModel = app.ModelNameAUVDropdown.Value;
            if ismember(auvModel, app.ModelNameAUVDropdown.ItemsData)
                % Preset model use display name in Items list
                displayName = app.ModelNameAUVDropdown.Items{str2double(auvModel)};
            else
                % Custom model
                displayName = auvModel;
            end

            % Display AUV model name in sidebar 
            
            % Get current configured AUV display
            currentDisplay = app.savedAUVdisplay.Value;

            % If first AUV saved, or configuring only one AUV, overwrites prior-saved AUV
            if isempty(currentDisplay) || all(cellfun(@(x) isempty(strtrim(x)), currentDisplay)) || (app.DependentVariableDropDown.Value == 2)
                app.savedAUVdisplay.Value = {displayName};
            else
                % If configuring multiple AUVs, appendsd the 'n'th AUV...
                app.savedAUVdisplay.Value = [currentDisplay; {displayName}];
            end

            % Saves auv settings to auv class object (property of app...)
            % if 'n'th auv, appends object as 'n'th item of auv array...
            if isempty(app.auv) || (app.DependentVariableDropDown.Value == 2)
                app.auv = app.compileAUV();
            else
                app.auv(end+1) = app.compileAUV();
            end

            % Resets AUV tab values to default
            app.ModelNameAUVDropdown.Value = app.Defaults.ModelNameAUVDropdown;
            app.applyAUVModelSpecs();
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            % Disable 'run simulation' button & display progress bar..
            app.RunSimulationButton.Enable = false;
            d = uiprogressdlg(app.UIFigure, 'Title', 'Running Simulation...', 'Indeterminate', 'on');

            tic; profile on; %%%%%%%%%%%%%%%%%%%

            try
                % Get hardware objects (WEC, AUV, CentralBattery) from settings
                [app.auv, wec, energyStorage, modIn] = app.collectUIsettings();
    
                % Generate modelOutput object
                modOut = ModelOutput(modIn.simTime);
    
                % Determine dependent variable for-loop length
                switch modIn.depVar
                    case 'AUV Model'
                        loopLength = length(modIn.auvModels); 
                    case 'WEC Power Gen. / Wave Resource' 
                        switch modIn.resourceDataType
                            case 1
                                loopLength = 10;  %length(RM3); 
                            case 2
                                loopLength = length(modIn.rdVars.dataFiles);
                            case 3
                                loopLength = length(modIn.rdVars.meanPower);
                            case 4
                                loopLength = length(modIn.rdVars.dataFiles);
                            case 5
                                loopLength = length(modIn.rdVars.waveSpecTable.SignificantWaveHeight);
                            case 6
                                loopLength = length(modIn.rdVars.dataFiles);
                        end
                end
    
                % Calculate power Gen. & save before loop if using the same
                % generation each time
                if strcmp(modIn.depVar, 'AUV Model')
                    modIn.calcPowerGen(wec, modOut)
                end

                % Run simulation for each set of input properties
                for simCount = 1:loopLength

                    % Reset hardware class objects
                    wec.reset(); 
                    energyStorage.reset();
                    % May not need to reset auv object if switching objects
                    % each simulation..

                    % If switching resource data each simulation, need to
                    % re-calculate power gen & reset auv object
                    if strcmp(modIn.depVar, 'WEC Power Gen. / Wave Resource')
                        app.auv.reset();
                        modIn.calcPowerGen(wec, modOut, simCount);
                    end

                    switch modIn.depVar
                        case 'AUV Model'
                            simResults = runSimulation(app.auv(simCount), wec, energyStorage, modIn);

                        case 'WEC Power Gen. / Wave Resource'
                            simResults = runSimulation(app.auv, wec, energyStorage, modIn);
                    end
                    
                    % save results to modOut object
                    modOut.fleetSize(simCount) = simResults.fleetSize;
                    modOut.energyStorageBatteryLvl(:, simCount) = simResults.energyStorageBatteryLvl;
                    modOut.wecBatteryLvl(:, simCount) = simResults.wecBatteryLvl; 
                    modOut.auvBatteryLvl{simCount} = simResults.auvBatteryLvl;
                    modOut.auvSchedule{simCount} = simResults.auvSchedule; 
                    modOut.auvTimeOnMission{simCount} = simResults.auvTimeOnMission;
                    modOut.auvTimeOnMissionCorrected{simCount} = simResults.auvTimeOnMissionCorrected; 
                    modOut.auvFleet{simCount} = simResults.auvFleet; 
                    modOut.centralBatteryCapacity(simCount) = simResults.centralBatteryCapacity;
                    modOut.meanPowerGen(simCount) = simResults.meanPowerGen;
                    modOut.ratePwrUsed(simCount) = simResults.ratePwrUsed;
                    modOut.auvMissionLength(simCount) = simResults.auvMissionLength;
                    if (strcmp(modIn.depVar, 'AUV Model') && (simCount == 1)) || strcmp(modIn.depVar, 'WEC Power Gen. / Wave Resource')
                        modOut.powerGenMeans(:,simCount) = simResults.powerGenMeans; 
                    end
    
                    clear simResults

                end

                % Save results in 'outputData' folder
                % generate filename using input data filename
                auv = app.auv;  %#ok
                outputFilename = append("outputData/", app.dataMetadata.fileName, "_results_", char(datetime('now', 'Format', 'ddMMMyy_HHmm')), ".mat");
                save(outputFilename, 'auv', 'wec', 'energyStorage', 'modOut', 'modIn')
           
            catch e
                % Display error details
                uialert(app.UIFigure, e.message, 'Simulation Error');

                % Print full stack trace to command window for debug.
                fprintf('\nSimulation Error: %s\n', e.message);
                for i = 1:numel(e.stack)
                    fprintf(' in %s (line %d) \n', e.stack(i).name, e.stack(i).line);
                end

            end

            % plot / display results
            plotPwr = app.PowerGenerationvsTimeCheckBox.Value;
            plotCentralBattery = app.CentralBatteryLevelvsTimeCheckBox.Value;
            plotAUVbattery = app.AUVBatteryLevelsvsTimeCheckBox.Value;
            plotWorkingHours = app.AggregateWorkingHoursvsDependentVariableCheckBox.Value;

            % If all power tracking checkboxes are selected, plot them in
            % the same window
            if plotPwr && plotCentralBattery && plotAUVbattery
                modOut.plotPowerTrackerPanel(modIn);

            else
                if plotPwr
                    modOut.plotPowerGen();
                end
                
                if plotCentralBattery
                    modOut.plotCentralBatteryTracker(modIn);
                end

                if plotAUVbattery
                    modOut.plotAUVbatteryTracker(modIn);
                end
            end

            % Global statistic plot
            if plotWorkingHours
                modOut.plotWorkingHours(modIn);
            end
            
            % Re-enable button, end progress bar, bring GUI to front
            close(d); 
            figure(app.UIFigure);
            app.RunSimulationButton.Enable = true;

            toc; profile off; profile viewer; %%%%%%%%%%%%%%%%%%%
        end

        % Value changed function: CharacteristicDimensionmEditField
        function CharacteristicDimensionmEditFieldValueChanged(app, event)
            charDim = app.CharacteristicDimensionmEditField.Value;
            app.changeFieldTextColor(event.Source);
            app.HydrodynamicEfficiency0XXEditField.Value = (1.3 * charDim + 5.6)/100;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayoutMain = uigridlayout(app.UIFigure);
            app.OutputPlotsPanel = uipanel(app.GridLayoutMain);
            app.OutputPlotsPanel.Title = 'Output Plots';
            app.GridLayoutOutputPlots = uigridlayout(app.OutputPlotsPanel);
            app.Image = uiimage(app.GridLayoutOutputPlots);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pGen.png');
            app.Image_2 = uiimage(app.GridLayoutOutputPlots);
            app.Image_3 = uiimage(app.GridLayoutOutputPlots);
            app.Image_4 = uiimage(app.GridLayoutOutputPlots);
            app.PowerGenerationvsTimeCheckBox = uicheckbox(app.GridLayoutOutputPlots);
            app.PowerGenerationvsTimeCheckBox.Text = {'Power Generation '; 'vs. Time'};
            app.CentralBatteryLevelvsTimeCheckBox = uicheckbox(app.GridLayoutOutputPlots);
            app.CentralBatteryLevelvsTimeCheckBox.Text = {'Central Battery Level '; 'vs. Time'};
            app.AUVBatteryLevelsvsTimeCheckBox = uicheckbox(app.GridLayoutOutputPlots);
            app.AUVBatteryLevelsvsTimeCheckBox.Text = {'AUV Battery Level(s)'; 'vs. Time'};
            app.AggregateWorkingHoursvsDependentVariableCheckBox = uicheckbox(app.GridLayoutOutputPlots);
            app.AggregateWorkingHoursvsDependentVariableCheckBox.Text = {'Aggregate Working Hours '; '% vs. Dependent Variable'};
            app.HardwareSpecsPanel = uipanel(app.GridLayoutMain);
            app.HardwareSpecsPanel.Title = 'Hardware Specifications';
            app.TabGroup = uitabgroup(app.HardwareSpecsPanel);
            app.WECTab = uitab(app.TabGroup);
            app.WECTab.Title = 'WEC';
            app.ModelNameEditFieldLabel = uilabel(app.WECTab);
            app.ModelNameEditFieldLabel.Text = 'Model Name:';
            app.ModelNameWECEditField = uieditfield(app.WECTab, 'text');
            app.ModelNameWECEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.ModelNameWECEditField.Value = 'Default';
            app.CharacteristicDimensionmEditFieldLabel = uilabel(app.WECTab);
            app.CharacteristicDimensionmEditFieldLabel.Text = 'Characteristic Dimension [m]:';
            app.CharacteristicDimensionmEditField = uieditfield(app.WECTab, 'numeric');
            app.CharacteristicDimensionmEditField.LowerLimitInclusive = 'off';
            app.CharacteristicDimensionmEditField.Limits = [0 1000];
            app.CharacteristicDimensionmEditField.ValueChangedFcn = createCallbackFcn(app, @CharacteristicDimensionmEditFieldValueChanged, true);
            app.CharacteristicDimensionmEditField.Value = 1;
            app.HotelLoadWEditFieldLabel = uilabel(app.WECTab);
            app.HotelLoadWEditFieldLabel.Text = 'Hotel Load [W]:';
            app.HotelLoadWWECEditField = uieditfield(app.WECTab, 'numeric');
            app.HotelLoadWWECEditField.Limits = [0 Inf];
            app.HotelLoadWWECEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryCapacityWhEditFieldLabel = uilabel(app.WECTab);
            app.BatteryCapacityWhEditFieldLabel.Text = 'BatteryCapacity [Wh]:';
            app.BatteryCapacityWhWECEditField = uieditfield(app.WECTab, 'numeric');
            app.BatteryCapacityWhWECEditField.LowerLimitInclusive = 'off';
            app.BatteryCapacityWhWECEditField.Limits = [0 Inf];
            app.BatteryCapacityWhWECEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryCapacityWhWECEditField.Value = 1;
            app.HydrodynamicEfficiency0XXEditFieldLabel = uilabel(app.WECTab);
            app.HydrodynamicEfficiency0XXEditFieldLabel.Text = 'Hydrodynamic Efficiency (0.XX):';
            app.HydrodynamicEfficiency0XXEditField = uieditfield(app.WECTab, 'numeric');
            app.HydrodynamicEfficiency0XXEditField.Limits = [0 1];
            app.HydrodynamicEfficiency0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.HydrodynamicEfficiency0XXEditField.Value = 1;
            app.GeneratorEfficiency0XXEditFieldLabel = uilabel(app.WECTab);
            app.GeneratorEfficiency0XXEditFieldLabel.Text = 'Generator Efficiency (0.XX):';
            app.GeneratorEfficiency0XXEditField = uieditfield(app.WECTab, 'numeric');
            app.GeneratorEfficiency0XXEditField.Limits = [0 1];
            app.GeneratorEfficiency0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.GeneratorEfficiency0XXEditField.Value = 1;
            app.BatteryEfficiency0XXLabel = uilabel(app.WECTab);
            app.BatteryEfficiency0XXLabel.Text = 'Battery Efficiency (0.XX):';
            app.BatteryEfficiency0XXWECEditField = uieditfield(app.WECTab, 'numeric');
            app.BatteryEfficiency0XXWECEditField.Limits = [0 1];
            app.BatteryEfficiency0XXWECEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryEfficiency0XXWECEditField.Value = 1;
            app.AUVTab = uitab(app.TabGroup);
            app.AUVTab.Title = 'AUV';
            app.MasskgEditFieldLabel = uilabel(app.AUVTab);
            app.MasskgEditFieldLabel.Text = 'Mass [kg]:';
            app.MissionTimehrEditFieldLabel = uilabel(app.AUVTab);
            app.MissionTimehrEditFieldLabel.Text = 'Mission Time [hr]:';
            app.MissionBatteryUsed0XXEditFieldLabel = uilabel(app.AUVTab);
            app.MissionBatteryUsed0XXEditFieldLabel.Text = 'Mission Battery (%) Used (0.XX):';
            app.BatteryCapacityWhEditField_2Label = uilabel(app.AUVTab);
            app.BatteryCapacityWhEditField_2Label.Text = 'Battery Capacity [Wh]:';
            app.ChargeRateWEditFieldLabel = uilabel(app.AUVTab);
            app.ChargeRateWEditFieldLabel.Text = 'Charge Rate [W]:';
            app.ChargingSwitchLabel = uilabel(app.AUVTab);
            app.ChargingSwitchLabel.Text = 'Charging';
            app.HotelLoadWEditField_2Label = uilabel(app.AUVTab);
            app.HotelLoadWEditField_2Label.Text = 'Hotel Load [W]:';
            app.PowerTransferEfficiency0XXEditFieldLabel = uilabel(app.AUVTab);
            app.PowerTransferEfficiency0XXEditFieldLabel.Text = 'Power Transfer Efficiency (0.XX):';
            app.PowerTransferEfficiency0XXEditField = uieditfield(app.AUVTab, 'numeric');
            app.PowerTransferEfficiency0XXEditField.LowerLimitInclusive = 'off';
            app.PowerTransferEfficiency0XXEditField.Limits = [0 1];
            app.PowerTransferEfficiency0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.PowerTransferEfficiency0XXEditField.Value = 1;
            app.BatteryEfficiency0XXEditField_2Label = uilabel(app.AUVTab);
            app.BatteryEfficiency0XXEditField_2Label.Text = 'Battery Efficiency (0.XX):';
            app.BatteryEfficiency0XXAUVEditField = uieditfield(app.AUVTab, 'numeric');
            app.BatteryEfficiency0XXAUVEditField.LowerLimitInclusive = 'off';
            app.BatteryEfficiency0XXAUVEditField.Limits = [0 1];
            app.BatteryEfficiency0XXAUVEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryEfficiency0XXAUVEditField.Value = 1;
            app.BatteryRechargeThreshold0XXEditFieldLabel = uilabel(app.AUVTab);
            app.BatteryRechargeThreshold0XXEditFieldLabel.Text = 'Battery Recharge Threshold (0.XX):';
            app.BatteryRechargeThreshold0XXEditField = uieditfield(app.AUVTab, 'numeric');
            app.BatteryRechargeThreshold0XXEditField.Limits = [0 1];
            app.BatteryRechargeThreshold0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryRechargeThreshold0XXEditField.Value = 1;
            app.ChargingSwitch = uiswitch(app.AUVTab, 'slider');
            app.ChargingSwitch.Items = {'Wired', 'Wireless'};
            app.ChargingSwitch.ItemsData = [1 0];
            app.ChargingSwitch.ValueChangedFcn = createCallbackFcn(app, @ChargingSwitchValueChanged, true);
            app.ChargingSwitch.Value = 0;
            app.ChargeRateWEditField = uieditfield(app.AUVTab, 'numeric');
            app.ChargeRateWEditField.LowerLimitInclusive = 'off';
            app.ChargeRateWEditField.Limits = [0 Inf];
            app.ChargeRateWEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.ChargeRateWEditField.Value = 1;
            app.HotelLoadWAUVEditField = uieditfield(app.AUVTab, 'numeric');
            app.HotelLoadWAUVEditField.LowerLimitInclusive = 'off';
            app.HotelLoadWAUVEditField.Limits = [0 Inf];
            app.HotelLoadWAUVEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.HotelLoadWAUVEditField.Value = 1;
            app.MissionBatteryUsed0XXEditField = uieditfield(app.AUVTab, 'numeric');
            app.MissionBatteryUsed0XXEditField.LowerLimitInclusive = 'off';
            app.MissionBatteryUsed0XXEditField.Limits = [0 1];
            app.MissionBatteryUsed0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.MissionBatteryUsed0XXEditField.Value = 0.99;
            app.MissionTimehrEditField = uieditfield(app.AUVTab, 'numeric');
            app.MissionTimehrEditField.LowerLimitInclusive = 'off';
            app.MissionTimehrEditField.Limits = [0 Inf];
            app.MissionTimehrEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.MissionTimehrEditField.Value = 1;
            app.BatteryCapacityWhAUVEditField = uieditfield(app.AUVTab, 'numeric');
            app.BatteryCapacityWhAUVEditField.LowerLimitInclusive = 'off';
            app.BatteryCapacityWhAUVEditField.Limits = [0 Inf];
            app.BatteryCapacityWhAUVEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryCapacityWhAUVEditField.Value = 1;
            app.MasskgEditField = uieditfield(app.AUVTab, 'numeric');
            app.MasskgEditField.LowerLimitInclusive = 'off';
            app.MasskgEditField.Limits = [0 Inf];
            app.MasskgEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.MasskgEditField.Value = 1;
            app.ModelNameLabel = uilabel(app.AUVTab);
            app.ModelNameLabel.Text = 'Model Name:';
            app.ModelNameAUVDropdown = uidropdown(app.AUVTab);
            app.ModelNameAUVDropdown.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U'};
            app.ModelNameAUVDropdown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21'};
            app.ModelNameAUVDropdown.Editable = 'on';
            app.ModelNameAUVDropdown.ValueChangedFcn = createCallbackFcn(app, @ModelNameAUVDropdownValueChanged, true);
            app.ModelNameAUVDropdown.Value = '1';
            app.ConfiguredAUVsPanel = uipanel(app.AUVTab);
            app.ConfiguredAUVsPanel.Title = 'Configured AUVs';
            app.savedAUVdisplay = uitextarea(app.ConfiguredAUVsPanel);
            app.savedAUVdisplay.Editable = 'off';
            app.SaveAUVButton = uibutton(app.AUVTab, 'push');
            app.SaveAUVButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAUVButtonPushed, true);
            app.SaveAUVButton.Text = 'Save AUV';
            app.EnergyStorageTab = uitab(app.TabGroup);
            app.EnergyStorageTab.Title = 'Energy Storage';
            app.BatteryEfficiency0XXEditField_3Label = uilabel(app.EnergyStorageTab);
            app.BatteryEfficiency0XXEditField_3Label.Text = 'Battery Efficiency (0.XX): ';
            app.BatteryEfficiency0XXESEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.BatteryEfficiency0XXESEditField.LowerLimitInclusive = 'off';
            app.BatteryEfficiency0XXESEditField.Limits = [0 1];
            app.BatteryEfficiency0XXESEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.BatteryEfficiency0XXESEditField.Value = 1;
            app.AUVDockPowerTransferEfficiency0XXEditFieldLabel = uilabel(app.EnergyStorageTab);
            app.AUVDockPowerTransferEfficiency0XXEditFieldLabel.Text = 'AUV Dock Power Transfer Efficiency (0.XX):';
            app.AUVDockPowerTransferEfficiency0XXEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.AUVDockPowerTransferEfficiency0XXEditField.LowerLimitInclusive = 'off';
            app.AUVDockPowerTransferEfficiency0XXEditField.Limits = [0 1];
            app.AUVDockPowerTransferEfficiency0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.AUVDockPowerTransferEfficiency0XXEditField.Value = 1;
            app.WECPowerTransferEfficiency0XXEditFieldLabel = uilabel(app.EnergyStorageTab);
            app.WECPowerTransferEfficiency0XXEditFieldLabel.Text = 'WEC Power Transfer Efficiency (0.XX):';
            app.WECPowerTransferEfficiency0XXEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.WECPowerTransferEfficiency0XXEditField.LowerLimitInclusive = 'off';
            app.WECPowerTransferEfficiency0XXEditField.Limits = [0 1];
            app.WECPowerTransferEfficiency0XXEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.WECPowerTransferEfficiency0XXEditField.Value = 1;
            app.PredefinedCentralBatteryCapacityWhCheckBox = uicheckbox(app.EnergyStorageTab);
            app.PredefinedCentralBatteryCapacityWhCheckBox.ValueChangedFcn = createCallbackFcn(app, @AppModifyingValueChanged, true);
            app.PredefinedCentralBatteryCapacityWhCheckBox.Text = 'Predefined Central Battery Capacity [Wh]:';
            app.PredefinedCentralBatteryCapacityWhEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.PredefinedCentralBatteryCapacityWhEditField.Limits = [0 Inf];
            app.PredefinedCentralBatteryCapacityWhEditField.AllowEmpty = 'on';
            app.PredefinedCentralBatteryCapacityWhEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.PredefinedCentralBatteryCapacityWhEditField.Enable = 'off';
            app.PredefinedCentralBatteryCapacityWhEditField.Value = [];
            app.BaseHotelLoadEditFieldLabel = uilabel(app.EnergyStorageTab);
            app.BaseHotelLoadEditFieldLabel.Text = 'Base Hotel Load:';
            app.BaseHotelLoadEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.BaseHotelLoadEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.AUVDockHotelLoadEditFieldLabel = uilabel(app.EnergyStorageTab);
            app.AUVDockHotelLoadEditFieldLabel.Text = 'AUV Dock Hotel Load';
            app.AUVDockHotelLoadEditField = uieditfield(app.EnergyStorageTab, 'numeric');
            app.AUVDockHotelLoadEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.SimulationSettingsPanel = uipanel(app.GridLayoutMain);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.StaggerAUVDeploymentsCheckBox = uicheckbox(app.SimulationSettingsPanel);
            app.StaggerAUVDeploymentsCheckBox.Text = 'Stagger AUV Deployments';
            app.StaggerAUVDeploymentsCheckBox.Value = true;
            app.LimitMaxAUVFleetSizeCheckBox = uicheckbox(app.SimulationSettingsPanel);
            app.LimitMaxAUVFleetSizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AppModifyingValueChanged, true);
            app.LimitMaxAUVFleetSizeCheckBox.Text = 'Limit Max AUV Fleet Size';
            app.LimitMaxAUVFleetSizeEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.LimitMaxAUVFleetSizeEditField.Limits = [0 Inf];
            app.LimitMaxAUVFleetSizeEditField.RoundFractionalValues = 'on';
            app.LimitMaxAUVFleetSizeEditField.AllowEmpty = 'on';
            app.LimitMaxAUVFleetSizeEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.LimitMaxAUVFleetSizeEditField.Enable = 'off';
            app.LimitMaxAUVFleetSizeEditField.Value = [];
            app.ResourceDataPwrTimeSeriesUploadPanel = uipanel(app.SimulationSettingsPanel);
            app.ResourceDataPwrTimeSeriesUploadPanel.Title = 'Resource Data';
            app.ResourceDataPwrTimeSeriesUploadPanel.Tag = 'resourceDataPanel';
            app.BrowsePwrButton = uibutton(app.ResourceDataPwrTimeSeriesUploadPanel, 'push');
            app.BrowsePwrButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowsePwrButton.Text = 'Browse';
            app.SelectresourcedatafilePwrLabel = uilabel(app.ResourceDataPwrTimeSeriesUploadPanel);
            app.SelectresourcedatafilePwrLabel.Text = 'Select resource data file...';
            app.VariableSelectionPwrData = uipanel(app.ResourceDataPwrTimeSeriesUploadPanel);
            app.VariableSelectionPwrData.Tag = 'VariableSelectionPanel';
            app.SelectpowervariableDropDownLabel = uilabel(app.VariableSelectionPwrData);
            app.SelectpowervariableDropDownLabel.Text = 'Select power variable: ';
            app.SelectpowervariableDropDown = uidropdown(app.VariableSelectionPwrData);
            app.SelectpowervariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelectpowervariableDropDown.Tag = 'pwrVectorVar';
            app.SelecttimevariableLabel = uilabel(app.VariableSelectionPwrData);
            app.SelecttimevariableLabel.Text = 'Select time variable:';
            app.SelecttimevariableDropDown = uidropdown(app.VariableSelectionPwrData);
            app.SelecttimevariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelecttimevariableDropDown.Tag = 'timeVar';
            app.DatafileTextAreaLabel_3 = uilabel(app.ResourceDataPwrTimeSeriesUploadPanel);
            app.DatafileTextAreaLabel_3.Text = 'Data file:';
            app.DatafilePwrTextArea = uitextarea(app.ResourceDataPwrTimeSeriesUploadPanel);
            app.DatafilePwrTextArea.Tag = 'dataFileDisplay';
            app.DatafilePwrTextArea.Editable = 'off';
            app.ResourceDataMeanPowerGenPanel = uipanel(app.SimulationSettingsPanel);
            app.ResourceDataMeanPowerGenPanel.Title = 'Resource Data';
            app.ResourceDataMeanPowerGenPanel.Tag = 'resourceDataPanel';
            app.EntermeanpowergenerationWEditField_2Label_2 = uilabel(app.ResourceDataMeanPowerGenPanel);
            app.EntermeanpowergenerationWEditField_2Label_2.Text = 'Enter mean power generation [W]:';
            app.EntermeanpowergenerationWEditField = uieditfield(app.ResourceDataMeanPowerGenPanel, 'numeric');
            app.EntermeanpowergenerationWEditField.Limits = [0 Inf];
            app.EntermeanpowergenerationWEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.ResourceDataMeanWavePanel = uipanel(app.SimulationSettingsPanel);
            app.ResourceDataMeanWavePanel.Title = 'Resource Data';
            app.ResourceDataMeanWavePanel.Tag = 'resourceDataPanel';
            app.EntermeansignificantwaveheightmEditField_2Label = uilabel(app.ResourceDataMeanWavePanel);
            app.EntermeansignificantwaveheightmEditField_2Label.Text = 'Enter mean significant wave height [m]:';
            app.EntermeansignificantwaveheightmEditField = uieditfield(app.ResourceDataMeanWavePanel, 'numeric');
            app.EntermeansignificantwaveheightmEditField.LowerLimitInclusive = 'off';
            app.EntermeansignificantwaveheightmEditField.Limits = [0 Inf];
            app.EntermeansignificantwaveheightmEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.EntermeansignificantwaveheightmEditField.Value = 1;
            app.EntermeanwaveenergyperiodsEditField_2Label = uilabel(app.ResourceDataMeanWavePanel);
            app.EntermeanwaveenergyperiodsEditField_2Label.Text = 'Enter mean wave energy period [s]:';
            app.EntermeanwaveenergyperiodsEditField = uieditfield(app.ResourceDataMeanWavePanel, 'numeric');
            app.EntermeanwaveenergyperiodsEditField.LowerLimitInclusive = 'off';
            app.EntermeanwaveenergyperiodsEditField.Limits = [0 Inf];
            app.EntermeanwaveenergyperiodsEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.EntermeanwaveenergyperiodsEditField.Value = 1;
            app.ResourceDataSeaStatePanel = uipanel(app.SimulationSettingsPanel);
            app.ResourceDataSeaStatePanel.Title = 'Resource Data';
            app.ResourceDataSeaStatePanel.Tag = 'resourceDataPanel';
            app.SelectSeaStateLabel = uilabel(app.ResourceDataSeaStatePanel);
            app.SelectSeaStateLabel.Text = 'Select Sea State...';
            app.SelectSeaStateDropDown = uidropdown(app.ResourceDataSeaStatePanel);
            app.SelectSeaStateDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.SelectSeaStateDropDown.Value = '3';
            app.ResourceDataPwrMatTimeSeriesUploadPanel = uipanel(app.SimulationSettingsPanel);
            app.ResourceDataPwrMatTimeSeriesUploadPanel.Title = 'Resource Data';
            app.ResourceDataPwrMatTimeSeriesUploadPanel.Tag = 'resourceDataPanel';
            app.BrowsePwrMatButton = uibutton(app.ResourceDataPwrMatTimeSeriesUploadPanel, 'push');
            app.BrowsePwrMatButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowsePwrMatButton.Text = 'Browse';
            app.SelectresourcedatafilePwrMatLabel = uilabel(app.ResourceDataPwrMatTimeSeriesUploadPanel);
            app.SelectresourcedatafilePwrMatLabel.Text = 'Select resource data file...';
            app.VariableSelectionPwrMatData = uipanel(app.ResourceDataPwrMatTimeSeriesUploadPanel);
            app.VariableSelectionPwrMatData.Tag = 'VariableSelectionPanel';
            app.SelectpowermatrixvariableLabel = uilabel(app.VariableSelectionPwrMatData);
            app.SelectpowermatrixvariableLabel.Text = 'Select power matrix variable: ';
            app.SelectpowervariablePwrMatDropDown = uidropdown(app.VariableSelectionPwrMatData);
            app.SelectpowervariablePwrMatDropDown.ValueChangedFcn = createCallbackFcn(app, @MatrixVariableDropDownValueChanged, true);
            app.SelectpowervariablePwrMatDropDown.Tag = 'pwrMatrixVar';
            app.SelecttimevariableDropDown_3Label = uilabel(app.VariableSelectionPwrMatData);
            app.SelecttimevariableDropDown_3Label.Text = 'Select time variable:';
            app.SelecttimevariablePwrMatDropDown = uidropdown(app.VariableSelectionPwrMatData);
            app.SelecttimevariablePwrMatDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelecttimevariablePwrMatDropDown.Tag = 'timeVar';
            app.SelecttimevariableDropDown_3Label_2 = uilabel(app.VariableSelectionPwrMatData);
            app.SelecttimevariableDropDown_3Label_2.Text = 'Select significant wave height:';
            app.SelectsignificantwaveheightPwrMatDropDown = uidropdown(app.VariableSelectionPwrMatData);
            app.SelectsignificantwaveheightPwrMatDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelectsignificantwaveheightPwrMatDropDown.Tag = 'sigWaveHeightVar';
            app.SelecttimevariableDropDown_3Label_3 = uilabel(app.VariableSelectionPwrMatData);
            app.SelecttimevariableDropDown_3Label_3.Text = 'Select wave energy period:';
            app.SelectwaveenergyperiodPwrMatDropDown = uidropdown(app.VariableSelectionPwrMatData);
            app.SelectwaveenergyperiodPwrMatDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelectwaveenergyperiodPwrMatDropDown.Tag = 'wavePeriodVar';
            app.DatafileTextAreaLabel_2 = uilabel(app.ResourceDataPwrMatTimeSeriesUploadPanel);
            app.DatafileTextAreaLabel_2.Text = 'Data file:';
            app.DatafilePwrMatTextArea = uitextarea(app.ResourceDataPwrMatTimeSeriesUploadPanel);
            app.DatafilePwrMatTextArea.Tag = 'dataFileDisplay';
            app.DatafilePwrMatTextArea.Editable = 'off';
            app.ResourceDataWaveTimeSeriesUploadPanel = uipanel(app.SimulationSettingsPanel);
            app.ResourceDataWaveTimeSeriesUploadPanel.Title = 'Resource Data';
            app.ResourceDataWaveTimeSeriesUploadPanel.Tag = 'resourceDataPanel';
            app.VariableSelectionWaveData = uipanel(app.ResourceDataWaveTimeSeriesUploadPanel);
            app.VariableSelectionWaveData.Tag = 'VariableSelectionPanel';
            app.SelectsignificantwaveheightvariableDropDownLabel = uilabel(app.VariableSelectionWaveData);
            app.SelectsignificantwaveheightvariableDropDownLabel.Text = 'Select significant wave height variable:';
            app.SelectsignificantwaveheightvariableDropDown = uidropdown(app.VariableSelectionWaveData);
            app.SelectsignificantwaveheightvariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelectsignificantwaveheightvariableDropDown.Tag = 'sigWaveHeightVar';
            app.SelectwaveenergyperiodvariableDropDownLabel = uilabel(app.VariableSelectionWaveData);
            app.SelectwaveenergyperiodvariableDropDownLabel.Text = 'Select wave energy period variable: ';
            app.SelectwaveenergyperiodvariableDropDown = uidropdown(app.VariableSelectionWaveData);
            app.SelectwaveenergyperiodvariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelectwaveenergyperiodvariableDropDown.Tag = 'wavePeriodVar';
            app.SelectpeakperiodvariableDropDownLabel = uilabel(app.VariableSelectionWaveData);
            app.SelectpeakperiodvariableDropDownLabel.Text = 'Select peak period variable:';
            app.SelectpeakperiodvariableDropDown = uidropdown(app.VariableSelectionWaveData);
            app.SelectpeakperiodvariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelectpeakperiodvariableDropDown.Tag = 'peakPeriodVar';
            app.SelecttimevariableDropDown_2Label = uilabel(app.VariableSelectionWaveData);
            app.SelecttimevariableDropDown_2Label.Text = 'Select time variable:';
            app.SelecttimevariableDropDown_2 = uidropdown(app.VariableSelectionWaveData);
            app.SelecttimevariableDropDown_2.Items = {};
            app.SelecttimevariableDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VectorVariableDropDownValueChanged, true);
            app.SelecttimevariableDropDown_2.Tag = 'timeVar';
            app.SelecttimevariableDropDown_2.Value = {};
            app.BrowseWaveButton = uibutton(app.ResourceDataWaveTimeSeriesUploadPanel, 'push');
            app.BrowseWaveButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseWaveButton.Text = 'Browse';
            app.SelectresourcedatafileWaveLabel = uilabel(app.ResourceDataWaveTimeSeriesUploadPanel);
            app.SelectresourcedatafileWaveLabel.Text = 'Select resource data file...';
            app.DataisacsvtablecontainingtherequiredheadersCheckBox = uicheckbox(app.ResourceDataWaveTimeSeriesUploadPanel);
            app.DataisacsvtablecontainingtherequiredheadersCheckBox.ValueChangedFcn = createCallbackFcn(app, @csvCheckBoxValueChanged, true);
            app.DataisacsvtablecontainingtherequiredheadersCheckBox.Text = 'Data is a *.csv table containing the required headers';
            app.DatafileTextAreaLabel = uilabel(app.ResourceDataWaveTimeSeriesUploadPanel);
            app.DatafileTextAreaLabel.Text = 'Data file: ';
            app.DatafileWaveTextArea = uitextarea(app.ResourceDataWaveTimeSeriesUploadPanel);
            app.DatafileWaveTextArea.Tag = 'dataFileDisplay';
            app.DatafileWaveTextArea.Editable = 'off';
            app.SimulationDurationhrLabel = uilabel(app.SimulationSettingsPanel);
            app.SimulationDurationhrLabel.Text = 'Simulation Duration [hr]:';
            app.SimulationDurationhrEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.SimulationDurationhrEditField.LowerLimitInclusive = 'off';
            app.SimulationDurationhrEditField.Limits = [0 8640];
            app.SimulationDurationhrEditField.ValueChangedFcn = createCallbackFcn(app, @editFieldChanged, true);
            app.SimulationDurationhrEditField.Placeholder = '336';
            app.SimulationDurationhrEditField.Value = 1;
            app.SimulationTimestepsEditFieldLabel = uilabel(app.SimulationSettingsPanel);
            app.SimulationTimestepsEditFieldLabel.Text = 'Simulation Timestep [s]:';
            app.SimulationTimestepsEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.SimulationTimestepsEditField.LowerLimitInclusive = 'off';
            app.SimulationTimestepsEditField.Limits = [0 86400];
            app.SimulationTimestepsEditField.RoundFractionalValues = 'on';
            app.SimulationTimestepsEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimestepsEditFieldValueChanged, true);
            app.SimulationTimestepsEditField.Tag = 'simTimestep';
            app.SimulationTimestepsEditField.Value = 30;
            app.DependentVariableDropDownLabel = uilabel(app.SimulationSettingsPanel);
            app.DependentVariableDropDownLabel.Text = 'Dependent Variable';
            app.DependentVariableDropDown = uidropdown(app.SimulationSettingsPanel);
            app.DependentVariableDropDown.Items = {'AUV Model', 'WEC Power Gen. / Wave Resource'};
            app.DependentVariableDropDown.ItemsData = [1 2];
            app.DependentVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @DependentVariableDropDownValueChanged, true);
            app.DependentVariableDropDown.Value = 1;
            app.ResourceDataTypeDropDownLabel = uilabel(app.SimulationSettingsPanel);
            app.ResourceDataTypeDropDownLabel.Text = 'Resource Data Type';
            app.ResourceDataTypeDropDown = uidropdown(app.SimulationSettingsPanel);
            app.ResourceDataTypeDropDown.Items = {'Sea State Power Data', 'Power Gen. (Time Series)', 'Power Gen. (Mean Value)', 'Wave Specs. (Time Series)', 'Wave Specs. (Mean Value)', 'Power Matrix with Time Series'};
            app.ResourceDataTypeDropDown.ItemsData = [1 2 3 4 5 6];
            app.ResourceDataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AppModifyingValueChanged, true);
            app.ResourceDataTypeDropDown.Value = 2;
            app.RunSimulationButton = uibutton(app.GridLayoutMain, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.PowerModelGUILabel = uilabel(app.GridLayoutMain);
            app.PowerModelGUILabel.Text = ' Power Model GUI';
            app.WarningTextArea = uitextarea(app.GridLayoutMain);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = powerModelGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end